classdef DoorKickersArmory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        compare                         matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        GridLayout26                    matlab.ui.container.GridLayout
        Panel_10                        matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        UITable_ReferenceWeapon         matlab.ui.control.Table
        Panel_9                         matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        UITable_CurrentWeapon           matlab.ui.control.Table
        Panel_8                         matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        Spinner_CurveMaxDistance        matlab.ui.control.Spinner
        mButton_50m                     matlab.ui.control.Button
        mButton_25m                     matlab.ui.control.Button
        mButton_15m                     matlab.ui.control.Button
        mButton_7m                      matlab.ui.control.Button
        Slider_CurveMaxDistance         matlab.ui.control.Slider
        GridLayout27                    matlab.ui.container.GridLayout
        CheckBox_CritKill               matlab.ui.control.CheckBox
        DropDown_CurveType              matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Button_SetRef                   matlab.ui.control.Button
        Button_ClearRef                 matlab.ui.control.Button
        UITable_ConstantStatistics      matlab.ui.control.Table
        config                          matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        TabGroup_DoctrineTroopClass     matlab.ui.container.TabGroup
        Tab_DoctrineOfRanger            matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        TabGroup_DoctrineOfRanger       matlab.ui.container.TabGroup
        Tab_Doctrine_Ranger_Pistol      matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        UITable_Doctrine_Rangers_Pistol  matlab.ui.control.Table
        Tab_3                           matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        UITable_Doctrine_Rangers_Rifle  matlab.ui.control.Table
        Tab_4                           matlab.ui.container.Tab
        GridLayout29_2                  matlab.ui.container.GridLayout
        UITable_Doctrine_Rangers_Support  matlab.ui.control.Table
        Tab_5                           matlab.ui.container.Tab
        GridLayout29_3                  matlab.ui.container.GridLayout
        UITable_Doctrine_Rangers_Elite  matlab.ui.control.Table
        GridLayout17                    matlab.ui.container.GridLayout
        Button_AllDoctrineOfRangers     matlab.ui.control.Button
        Button_ResetDoctrineOfRangers   matlab.ui.control.Button
        CheckBox_EnableTab_DoctrineOfRanger  matlab.ui.control.CheckBox
        Tab_DoctrineOfSWAT              matlab.ui.container.Tab
        GridLayout13_3                  matlab.ui.container.GridLayout
        TabGroup_DoctrineOfSWAT         matlab.ui.container.TabGroup
        Tab_Doctrine_SWAT_Pistol        matlab.ui.container.Tab
        GridLayout29_4                  matlab.ui.container.GridLayout
        UITable_Doctrine_SWAT_Pistol    matlab.ui.control.Table
        Tab_7                           matlab.ui.container.Tab
        GridLayout29_5                  matlab.ui.container.GridLayout
        UITable_Doctrine_SWAT_Rifle     matlab.ui.control.Table
        Tab_8                           matlab.ui.container.Tab
        GridLayout29_6                  matlab.ui.container.GridLayout
        UITable_Doctrine_SWAT_Support   matlab.ui.control.Table
        Tab_9                           matlab.ui.container.Tab
        GridLayout29_7                  matlab.ui.container.GridLayout
        UITable_Doctrine_SWAT_Elite     matlab.ui.control.Table
        GridLayout17_2                  matlab.ui.container.GridLayout
        Button_AllDoctrineOfSWAT        matlab.ui.control.Button
        Button_ResetDoctrineOfSWAT      matlab.ui.control.Button
        CheckBox_EnableTab_DoctrineOfSWAT  matlab.ui.control.CheckBox
        Tab_DoctrineOfCIA               matlab.ui.container.Tab
        GridLayout13_2                  matlab.ui.container.GridLayout
        TabGroup_DoctrineOfCIA          matlab.ui.container.TabGroup
        Tab_Doctrine_CIA_Pistol         matlab.ui.container.Tab
        GridLayout29_8                  matlab.ui.container.GridLayout
        UITable_Doctrine_CIA_Pistol     matlab.ui.control.Table
        Tab_11                          matlab.ui.container.Tab
        GridLayout29_9                  matlab.ui.container.GridLayout
        UITable_Doctrine_CIA_Rifle      matlab.ui.control.Table
        Tab_12                          matlab.ui.container.Tab
        GridLayout29_10                 matlab.ui.container.GridLayout
        UITable_Doctrine_CIA_Support    matlab.ui.control.Table
        GridLayout17_3                  matlab.ui.container.GridLayout
        Button_AllDoctrineOfCIA         matlab.ui.control.Button
        Button_ResetDoctrineOfCIA       matlab.ui.control.Button
        CheckBox_EnableTab_DoctrineOfCIA  matlab.ui.control.CheckBox
        Panel_3                         matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        Panel_7                         matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        DropDown_BattleHonor2           matlab.ui.control.DropDown
        DropDown_BattleHonor1           matlab.ui.control.DropDown
        Panel_6                         matlab.ui.container.Panel
        GridLayout20_2                  matlab.ui.container.GridLayout
        Spinner_FieldSkills             matlab.ui.control.Spinner
        Slider_FieldSkills              matlab.ui.control.Slider
        Panel_5                         matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        Spinner_AssaultShooting         matlab.ui.control.Spinner
        Slider_AssaultShooting          matlab.ui.control.Slider
        Panel_4                         matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        Spinner_Marksmanship            matlab.ui.control.Spinner
        Slider_Marksmanship             matlab.ui.control.Slider
        Panel                           matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Curve                           matlab.ui.control.UIAxes
        statistic                       matlab.ui.container.Panel
        selection                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_Ammo                      matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        ListBox_Ammo                    matlab.ui.control.ListBox
        Scope                           matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ListBox_Scope                   matlab.ui.control.ListBox
        Gun                             matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        CheckBox_Mounted                matlab.ui.control.CheckBox
        Lamp_Unlockable                 matlab.ui.control.Lamp
        Label_3                         matlab.ui.control.Label
        ListBox_Gun                     matlab.ui.control.ListBox
        Panel_Filter                    matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        Filter_MuzzleWeapon             matlab.ui.control.CheckBox
        Filter_NoMuzzleWeapon           matlab.ui.control.CheckBox
        Filter_SecondaryWeapon          matlab.ui.control.CheckBox
        Filter_PrimaryWeapon            matlab.ui.control.CheckBox
        Tree_Filter                     matlab.ui.container.CheckBoxTree
        Filter_Rangers                  matlab.ui.container.TreeNode
        Filter_Assault                  matlab.ui.container.TreeNode
        Filter_Support                  matlab.ui.container.TreeNode
        Filter_Marksman                 matlab.ui.container.TreeNode
        Filter_Grenadier                matlab.ui.container.TreeNode
        Filter_SWAT                     matlab.ui.container.TreeNode
        Filter_SwatLeader               matlab.ui.container.TreeNode
        Filter_SwatAssaulter            matlab.ui.container.TreeNode
        Filter_SwatSapper               matlab.ui.container.TreeNode
        Filter_SwatMilitia              matlab.ui.container.TreeNode
        Filter_CIA                      matlab.ui.container.TreeNode
        Filter_Undercover               matlab.ui.container.TreeNode
        Filter_BlackOps                 matlab.ui.container.TreeNode
    end

    
    properties (Access = private, Constant)
        doctrine_csv_file = "doctrines.csv";
        gun_length_csv_file = "gun_length.csv";
        soldier_class_ranger = ["Rangers"; "Assault"; "Support"; "Marksman"; "Grenadier"];
        soldier_class_cia    = ["CIA"; "Undercover"; "BlackOps"];
        soldier_class_swat   = ["SWAT"; "SwatLeader"; "SwatAssaulter"; "SwatSapper"; "SwatMilitia"];
        battle_honor_text = {'无', '进攻升级', '防御升级'};
        curve_type_text = { ...
            '击杀耗时(最长)', ...
            '击杀耗时(最短)', ...
            '命中率(首发)', ...
            '命中率(连发)', ...
            '暴击率', ...
            '瞄准时间', ...
            '恢复时间', ...
            '伤害', ...
            '穿透力', ...
            '射速' ...
            '最小连射' , ...
            '最大连射'  ...
        };
    end

    properties (Access = private)
        dir_path_of_steam = "D:\Games\Steam";

        equip_data
        doctrine_data
        innate_data

        gun_filter
        filtered_gun_list
        
        current_weapon
%         reference_weapon
    
        doctrine_record  % name, enabled, level, max_level, text
        doctrine_tree

        doctrine_enabled_rangers
        doctrine_enabled_swat
        doctrine_enabled_cia

        innate_marksmanship_value
        innate_assaultshooting_value

        battle_honor_1
        battle_honor_2

        %
        weapon_doctrine_statistics
        innate_statistics
        battle_honor_statistics
        final_statistics

        constant_statistics_table

        enemy_hp = 100
        max_distance  = 100
        distance_step = 0.1

        reference_statistics
        curve_max_distance
    end    
    
    methods (Access = private)
        
        function CurrentGunChanged(app)
            app.current_weapon.name = app.ListBox_Gun.Value;

            if (isempty(app.current_weapon.name))
                app.ListBox_Scope.Items    = {};
                app.ListBox_Ammo.Items     = {};
                app.current_weapon.info    = {};
                app.current_weapon.attack  = {};
                app.current_weapon.mountable  = false;
                app.current_weapon.mounted    = false;
                app.current_weapon.unlockable = false;
            else
                app.current_weapon.info = app.filtered_gun_list(strcmp(app.filtered_gun_list.name, app.current_weapon.name), :);
                app.current_weapon.attack = app.current_weapon.info.attackTypes{1};

                app.current_weapon.mountable = any(string({app.current_weapon.attack.inCoverOverride}) ~= "");
                app.current_weapon.unlockable = any(string({app.current_weapon.attack.disabled}) == "true");

                % scope and ammo list 
                scope_list = app.current_weapon.info.scope{1};
                ammo_list  = app.current_weapon.info.ammo{1};
                app.ListBox_Scope.Items = scope_list;
                app.ListBox_Ammo.Items  = ammo_list;

                if (isempty(app.ListBox_Scope.Value))
                    app.ListBox_Scope.Value = app.ListBox_Scope.Items{1};
                end
                if (isempty(app.ListBox_Ammo.Value))
                    app.ListBox_Ammo.Value = app.ListBox_Ammo.Items{1};
                end

            end

            app.CheckBox_Mounted.Value = 0;
            app.CheckBox_Mounted.Enable = app.current_weapon.mountable;

            if (app.current_weapon.unlockable)
                app.Lamp_Unlockable.Color = [0 1 0];
            else
                app.Lamp_Unlockable.Color = [1 0 0];
            end

            app.CurrentScopeAmmoChanged();
        end
        

        function CurrentScopeAmmoChanged(app)
            app.current_weapon.scope_name = app.ListBox_Scope.Value;
            app.current_weapon.ammo_name  = app.ListBox_Ammo.Value;

            if (isempty(app.current_weapon.scope_name) || isempty(app.current_weapon.ammo_name))
                app.NoWeaponSelected();
            else
                app.CurrentWeaponChanged();
            end
        end
        

        function NoWeaponSelected(app)
            app.final_statistics = app.ClearStatistics(app.final_statistics);
            app.UITable_CurrentWeapon.Data = {};
            app.UpdateFinalStatistics();
        end


        function CurrentWeaponChanged(app)
            app.current_weapon.mounted = app.CheckBox_Mounted.Value;

            if (app.CheckBox_Mounted.Value == 1)
                mounted_text = '开启';
            else
                mounted_text = '关闭';
            end
            app.UITable_CurrentWeapon.Data = {app.current_weapon.name, app.current_weapon.scope_name, app.current_weapon.ammo_name, mounted_text};

            app.UpdateWeaponDoctrineStatistics();
            app.UpdateFinalStatistics();
        end


        function loadDoctrineUITableData(~, uitable, data)
            uitable.ColumnName     = {'名称', '启用', '等级', '最大等级'};
            uitable.ColumnWidth    = 'fit';
            uitable.ColumnEditable = [false, true, true, false];
        
            n = height(data);
            empty_table = [repmat({''},n,1), num2cell(false(n,1)), num2cell(zeros(n,1)), num2cell(ones(n,1))];
            uitable.Data      = empty_table;
            uitable.Data(:,1) = data.text;
            uitable.Data(:,2) = num2cell(false(n, 1));
            uitable.Data(:,3) = num2cell(data.level);
            uitable.Data(:,4) = num2cell(data.maxlevel); 
        end

        
        function DoctrineChanged(app)
            app.UpdateWeaponDoctrineStatistics();
            app.UpdateFinalStatistics();
        end
        

        function InnateChanged(app)
            app.UpdateInnateStatistics();
            app.UpdateFinalStatistics();
        end


        function BattleHonorChanged(app)
            app.UpdateBattleHonorStatistics();
            app.UpdateFinalStatistics();
        end

        
        function CurveDistanceUpdated(app)
            if (app.curve_max_distance < 1)
                app.curve_max_distance = 1;
            end
            app.Slider_CurveMaxDistance.Value =  app.curve_max_distance;
            app.Spinner_CurveMaxDistance.Value = app.curve_max_distance;

            app.Curve.XLim = [0, app.curve_max_distance];
        end


        function UpdateWeaponDoctrineStatistics(app)
            app.weapon_doctrine_statistics = CalcWeaponDoctrineStatistics( ...
                app.max_distance                 , ...
                app.distance_step                , ...
                app.equip_data                   , ...
                app.doctrine_data                , ...
                app.innate_data                  , ...
                app.soldier_class_ranger         , ...
                app.soldier_class_swat           , ...
                app.soldier_class_cia            , ...
                app.current_weapon.name          , ...
                app.current_weapon.scope_name    , ...
                app.current_weapon.ammo_name     , ...
                app.current_weapon.mounted       , ...
                app.doctrine_record              , ...
                app.doctrine_enabled_rangers     , ...
                app.doctrine_enabled_swat        , ...
                app.doctrine_enabled_cia         , ...
                app.innate_marksmanship_value    , ...
                app.innate_assaultshooting_value , ...
                app.battle_honor_1               , ...
                app.battle_honor_2                 ...
            );
            app.UpdateInnateStatistics();
        end
        
        
        function UpdateInnateStatistics(app)
            app.innate_statistics = CalcInnateStatistics( ...
                app.innate_data                  , ...
                app.innate_marksmanship_value    , ...
                app.innate_assaultshooting_value , ...
                app.weapon_doctrine_statistics     ...
            );
            app.UpdateBattleHonorStatistics();
        end
        

        function UpdateBattleHonorStatistics(app)
            app.battle_honor_statistics = CalcBattleHonorStatistics( ...
                app.battle_honor_text            , ...
                app.battle_honor_1               , ...
                app.battle_honor_2               , ...
                app.innate_statistics              ...
            );
            app.UpdateTTK();
        end
        

        function UpdateFinalStatistics(app)
            app.constant_statistics_table = {
                "枪支类型"     , app.final_statistics.constant.category                , app.reference_statistics.constant.category                ;
%                 "收枪距离(米)" , app.final_statistics.constant.length                  , app.reference_statistics.constant.length                  ;
                "移速修正(%)"  , app.final_statistics.constant.moveSpeedModifierPercent, app.reference_statistics.constant.moveSpeedModifierPercent;
                "转向修正(%)"  , app.final_statistics.constant.turnSpeedModifierPercent, app.reference_statistics.constant.turnSpeedModifierPercent;
                "换弹耗时(ms)" , app.final_statistics.constant.reloadTime              , app.reference_statistics.constant.reloadTime              ;
                "空仓换弹(ms)" , app.final_statistics.constant.reloadEmptyTime         , app.reference_statistics.constant.reloadEmptyTime         ;
                "切入耗时(ms)" , app.final_statistics.constant.changeInTime            , app.reference_statistics.constant.changeInTime            ;
                "切出耗时(ms)" , app.final_statistics.constant.changeOutTime           , app.reference_statistics.constant.changeOutTime           ;
                "举枪耗时(ms)" , app.final_statistics.constant.readyTime               , app.reference_statistics.constant.readyTime               ;
                "收枪耗时(ms)" , app.final_statistics.constant.guardTime               , app.reference_statistics.constant.guardTime               ;
                "闭膛待击(0|1)", app.final_statistics.constant.closedBolt              , app.reference_statistics.constant.closedBolt              ;
                "循环装填(0|1)", app.final_statistics.constant.cyclicReload            , app.reference_statistics.constant.cyclicReload            ;
                "消音弹药(0|1)", app.final_statistics.constant.silenced                , app.reference_statistics.constant.silenced                ;
                "声响范围(米)" , app.final_statistics.constant.audibleSoundRadius      , app.reference_statistics.constant.audibleSoundRadius      ;
                "弹匣容量(个)" , app.final_statistics.constant.roundsPerMagazine       , app.reference_statistics.constant.roundsPerMagazine       ;
                "弹丸数量(个)" , app.final_statistics.constant.numPellets              , app.reference_statistics.constant.numPellets              ;
                "压制强度(?)"  , app.final_statistics.constant.suppressionScale        , app.reference_statistics.constant.suppressionScale        ;
                "冲击力度(?)"  , app.final_statistics.constant.physicsImpactForce      , app.reference_statistics.constant.physicsImpactForce      ;
            };
            
            app.constant_statistics_table = cell2table(app.constant_statistics_table, 'VariableNames', {'性能项', '当前值', '参考值'});
            app.UITable_ConstantStatistics.Data = app.constant_statistics_table;

            app.UpdateCurve();
        end
        

        function InitDoctrines(app)
            app.doctrine_enabled_rangers = false;
            app.doctrine_enabled_swat = false;
            app.doctrine_enabled_cia = false;

            app.loadDoctrineUITableData(app.UITable_Doctrine_Rangers_Pistol , app.doctrine_tree.Rangers.Pistol );
            app.loadDoctrineUITableData(app.UITable_Doctrine_Rangers_Rifle  , app.doctrine_tree.Rangers.Rifle  );
            app.loadDoctrineUITableData(app.UITable_Doctrine_Rangers_Support, app.doctrine_tree.Rangers.Support);
            app.loadDoctrineUITableData(app.UITable_Doctrine_Rangers_Elite  , app.doctrine_tree.Rangers.Elite  );

            app.loadDoctrineUITableData(app.UITable_Doctrine_SWAT_Pistol    , app.doctrine_tree.SWAT.Pistol    );
            app.loadDoctrineUITableData(app.UITable_Doctrine_SWAT_Rifle     , app.doctrine_tree.SWAT.Rifle     );
            app.loadDoctrineUITableData(app.UITable_Doctrine_SWAT_Support   , app.doctrine_tree.SWAT.Support   );
            app.loadDoctrineUITableData(app.UITable_Doctrine_SWAT_Elite     , app.doctrine_tree.SWAT.Elite     );

            app.loadDoctrineUITableData(app.UITable_Doctrine_CIA_Pistol     , app.doctrine_tree.CIA.Pistol     );
            app.loadDoctrineUITableData(app.UITable_Doctrine_CIA_Rifle      , app.doctrine_tree.CIA.Rifle      );
            app.loadDoctrineUITableData(app.UITable_Doctrine_CIA_Support    , app.doctrine_tree.CIA.Support    );

            app.TabGroup_DoctrineOfRanger.SelectedTab = app.Tab_Doctrine_Ranger_Pistol;
            app.TabGroup_DoctrineOfSWAT.SelectedTab   = app.Tab_Doctrine_SWAT_Pistol;
            app.TabGroup_DoctrineOfCIA.SelectedTab    = app.Tab_Doctrine_CIA_Pistol;
        end


        function InitInnate(app)
            app.innate_marksmanship_value = 50;
            app.innate_assaultshooting_value = 50;

            app.Slider_Marksmanship.Value = app.innate_marksmanship_value;
            app.Spinner_Marksmanship.Value = app.innate_marksmanship_value;

            app.Slider_AssaultShooting.Value = app.innate_assaultshooting_value;
            app.Spinner_AssaultShooting.Value = app.innate_assaultshooting_value;
        end


        function InitBattleHonor(app)
            app.battle_honor_1 = app.battle_honor_text{1};
            app.battle_honor_2 = app.battle_honor_text{1};

            app.DropDown_BattleHonor1.Items = app.battle_honor_text;
            app.DropDown_BattleHonor2.Items = app.battle_honor_text;

            app.DropDown_BattleHonor1.Value = app.battle_honor_1;
            app.DropDown_BattleHonor2.Value = app.battle_honor_2;
        end
        

        function statistics = ClearStatistics(app, statistics_in)
            statistics = statistics_in;

            statistics.curved.num_points = length(0: app.distance_step : app.max_distance);

            statistics.constant.category                 = nan;
            statistics.constant.length                   = nan;
            statistics.constant.moveSpeedModifierPercent = nan;
            statistics.constant.turnSpeedModifierPercent = nan;
            statistics.constant.reloadTime               = nan;
            statistics.constant.changeInTime             = nan;
            statistics.constant.changeOutTime            = nan;
            statistics.constant.readyTime                = nan;
            statistics.constant.guardTime                = nan;
            statistics.constant.reloadEmptyTime          = nan;
            statistics.constant.closedBolt               = nan;
            statistics.constant.cyclicReload             = nan;
            statistics.constant.silenced                 = nan;
            statistics.constant.audibleSoundRadius       = nan;
            statistics.constant.roundsPerMagazine        = nan;
            statistics.constant.numPellets               = nan;
            statistics.constant.suppressionScale         = nan;
            statistics.constant.physicsImpactForce       = nan;
            
            statistics.curved.aimTime              = nan(statistics.curved.num_points, 1);
            statistics.curved.accuracy             = nan(statistics.curved.num_points, 1);
            statistics.curved.followupShotAccuracy = nan(statistics.curved.num_points, 1);
            statistics.curved.resetTime            = nan(statistics.curved.num_points, 1);
            statistics.curved.critChance           = nan(statistics.curved.num_points, 1);
            statistics.curved.roundsPerSecond      = nan(statistics.curved.num_points, 1);
            statistics.curved.minShots             = nan(statistics.curved.num_points, 1);
            statistics.curved.maxShots             = nan(statistics.curved.num_points, 1);
            statistics.curved.damage               = nan(statistics.curved.num_points, 1);
            statistics.curved.penetration          = nan(statistics.curved.num_points, 1);
            statistics.curved.worst_ttk            = nan(statistics.curved.num_points, 1);
            statistics.curved.best_ttk             = nan(statistics.curved.num_points, 1);
        end
        

        function ClearRefrence(app)
            app.reference_statistics = app.ClearStatistics(app.reference_statistics);
            app.UITable_ReferenceWeapon.Data = {};
            app.UpdateFinalStatistics();
        end
        

        function SetReference(app)
            app.UITable_ReferenceWeapon.Data = app.UITable_CurrentWeapon.Data;
            app.reference_statistics = app.final_statistics;
            app.UpdateFinalStatistics();
        end
        

        function UpdateCurve(app)
            x = app.final_statistics.curved.sampling_distance;

            switch app.DropDown_CurveType.Value
            case app.DropDown_CurveType.Items{1} % '击杀耗时（最长）'     
                curve_data = [app.final_statistics.curved.worst_ttk                , app.reference_statistics.curved.worst_ttk];
            case app.DropDown_CurveType.Items{2} % '击杀耗时（最短）'     
                curve_data = [app.final_statistics.curved.best_ttk                 , app.reference_statistics.curved.best_ttk];
            case app.DropDown_CurveType.Items{3} % '命中率(首发)' 
                curve_data = [app.final_statistics.curved.accuracy                 , app.reference_statistics.curved.accuracy];
            case app.DropDown_CurveType.Items{4} % '命中率(连发)' 
                curve_data = [app.final_statistics.curved.followupShotAccuracy     , app.reference_statistics.curved.followupShotAccuracy];
            case app.DropDown_CurveType.Items{5} % '暴击率'       
                curve_data = [app.final_statistics.curved.critChance               , app.reference_statistics.curved.critChance];
            case app.DropDown_CurveType.Items{6} % '瞄准时间'     
                curve_data = [app.final_statistics.curved.aimTime                  , app.reference_statistics.curved.aimTime];
            case app.DropDown_CurveType.Items{7} % '恢复时间'     
                curve_data = [app.final_statistics.curved.resetTime                , app.reference_statistics.curved.resetTime];
            case app.DropDown_CurveType.Items{8} % '伤害'         
                curve_data = [app.final_statistics.curved.damage                   , app.reference_statistics.curved.damage];
            case app.DropDown_CurveType.Items{9} % '穿透力'       
                curve_data = [app.final_statistics.curved.penetration              , app.reference_statistics.curved.penetration];
            case app.DropDown_CurveType.Items{10} % '射速'         
                curve_data = [app.final_statistics.curved.roundsPerSecond          , app.reference_statistics.curved.roundsPerSecond];
            case app.DropDown_CurveType.Items{11} % '最小连射'         
                curve_data = [app.final_statistics.curved.minShots                 , app.reference_statistics.curved.minShots];
            case app.DropDown_CurveType.Items{12} % '最大连射'         
                curve_data = [app.final_statistics.curved.maxShots                 , app.reference_statistics.curved.maxShots];
            end

            plot(app.Curve, x, curve_data);  
            y_lim = 1.1 * max(curve_data, [], 'all');

            if (~isnan(y_lim))
                app.Curve.YLim = [0, y_lim];
            end
        end

        
        function UpdateTTK(app)
            app.final_statistics = CalcTTK(app.battle_honor_statistics, app.enemy_hp, app.CheckBox_CritKill.Value);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fid = fopen("steam_path.txt");
            if (fid < 0)
                error("Error: steam_path.txt not found");
            end
            app.dir_path_of_steam = fgetl(fid);
            fclose(fid);

            [app.equip_data, app.doctrine_data, app.innate_data] = loadGameData(app.dir_path_of_steam, app.gun_length_csv_file);
            [app.doctrine_record, app.doctrine_tree] = loadDoctrineFromCSV(app.doctrine_csv_file, app.soldier_class_ranger(1), app.soldier_class_swat(1), app.soldier_class_cia(1));
            
%             app.Curve.Title.String = app.DropDown_CurveType.Value;
            app.Tree_Filter.CheckedNodes = [app.Filter_Assault];
            app.DropDown_CurveType.Items = app.curve_type_text;
            app.DropDown_CurveType.Value = app.curve_type_text{1};

            app.InitDoctrines();
            app.InitInnate();
            app.InitBattleHonor();

            app.reference_statistics = app.ClearStatistics(app.reference_statistics);
            app.FilterGuns();
        end

        % Callback function: Filter_MuzzleWeapon, Filter_NoMuzzleWeapon, 
        % ...and 3 other components
        function FilterGuns(app, event)
            checked_nodes = app.Tree_Filter.CheckedNodes;
            
            if (isempty(checked_nodes))
                filtered_guns = [];
            else
                filtered_guns = app.equip_data.gun;
                filtered_guns = filtered_guns(contains(filtered_guns.inventoryBinding, "Weapon"), :);

                %% gun class filtering
                filter_primary   = (app.Filter_PrimaryWeapon.Value   == 0);
                filter_secondary = (app.Filter_SecondaryWeapon.Value == 0);
                filter_muzzle    = (app.Filter_MuzzleWeapon.Value    == 0);
                filter_no_muzzle = (app.Filter_NoMuzzleWeapon.Value  == 0);
                
                if (filter_primary)
                    filtered_guns = filtered_guns(~contains(filtered_guns.inventoryBinding, "PrimaryWeapon"), :);
                end
                if (filter_secondary)
                    filtered_guns = filtered_guns(~contains(filtered_guns.inventoryBinding, "SecondaryWeapon"), :);
                end
                if (filter_muzzle)
                    filtered_guns = filtered_guns(~contains(filtered_guns.inventoryBinding, "Muzzle"), :);
                end
                if (filter_no_muzzle)
                    filtered_guns = filtered_guns(contains(filtered_guns.inventoryBinding, "Muzzle"), :);
                end

                %% soldier class filtering
                soldier_class = string({checked_nodes.NodeData})';
                if (~ismember(app.soldier_class_ranger(1), soldier_class))
                    if (any(ismember(app.soldier_class_ranger(2:end), soldier_class)))
                        soldier_class = [app.soldier_class_ranger(1); soldier_class];
                    end
                end
                if (~ismember(app.soldier_class_cia(1), soldier_class))
                    if (any(ismember(app.soldier_class_cia(2:end), soldier_class)))
                        soldier_class = [app.soldier_class_cia(1); soldier_class];
                    end
                end
                if (~ismember(app.soldier_class_swat(1), soldier_class))
                    if (any(ismember(app.soldier_class_swat(2:end), soldier_class)))
                        soldier_class = [app.soldier_class_swat(1); soldier_class];
                    end
                end
                
                FilterFunc = @(class, type) any(ismember(class{1}, type{1}));
                soldier_class_cell = repmat({soldier_class}, size(filtered_guns,1) ,1);
                idx = arrayfun(FilterFunc, soldier_class_cell, filtered_guns.type);
                filtered_guns = filtered_guns(idx, :);
            end

            if (isempty(filtered_guns))
                app.filtered_gun_list = [];
                app.ListBox_Gun.Items = {};
            else
                app.filtered_gun_list = filtered_guns;
                app.ListBox_Gun.Items = app.filtered_gun_list.name;
                if (isempty(app.ListBox_Gun.Value))
                    app.ListBox_Gun.Value = app.ListBox_Gun.Items(1);
                end
            end

            app.CurrentGunChanged();
        end

        % Value changed function: ListBox_Gun
        function ListBox_GunValueChanged(app, event)
            app.CurrentGunChanged();
        end

        % Value changed function: ListBox_Scope
        function ListBox_ScopeValueChanged(app, event)
            app.CurrentScopeAmmoChanged();
        end

        % Value changed function: ListBox_Ammo
        function ListBox_AmmoValueChanged(app, event)
            app.CurrentScopeAmmoChanged();
        end

        % Value changed function: CheckBox_Mounted
        function CheckBox_MountedValueChanged(app, event)
            app.CurrentWeaponChanged();
        end

        % Value changing function: Slider_Marksmanship
        function Slider_MarksmanshipValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_Marksmanship.Value = changingValue;
        end

        % Value changing function: Spinner_Marksmanship
        function Spinner_MarksmanshipValueChanging(app, event)
            changingValue = event.Value;
            app.Slider_Marksmanship.Value = changingValue;
        end

        % Value changing function: Spinner_AssaultShooting
        function Spinner_AssaultShootingValueChanging(app, event)
            changingValue = event.Value;
            app.Slider_AssaultShooting.Value = changingValue;
        end

        % Value changing function: Slider_AssaultShooting
        function Slider_AssaultShootingValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_AssaultShooting.Value = changingValue;
        end

        % Value changing function: Spinner_FieldSkills
        function Spinner_FieldSkillsValueChanging(app, event)
            changingValue = event.Value;
            app.Slider_FieldSkills.Value = changingValue;
        end

        % Value changing function: Slider_FieldSkills
        function Slider_FieldSkillsValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_FieldSkills.Value = changingValue;
        end

        % Value changed function: DropDown_CurveType
        function DropDown_CurveTypeValueChanged(app, event)
            value = app.DropDown_CurveType.Value;
            app.Curve.Title.String = value;
            app.UpdateCurve();
        end

        % Cell edit callback: UITable_Doctrine_CIA_Pistol, 
        % ...and 10 other components
        function UITable_Doctrine_CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if (indices(2) == 3)
                if (newData < 0)
                    event.Source.Data{indices(1), indices(2)} = 0;
                elseif (newData > event.Source.Data{indices(1), 4})
                    event.Source.Data{indices(1), indices(2)} = event.Source.Data{indices(1), 4};
                end
            end

            doctrine_text    = event.Source.Data{indices(1), 1};
            doctrine_enabled = event.Source.Data{indices(1), 2};
            doctrine_level   = event.Source.Data{indices(1), 3};

            % update doctrine record
            row_index = strcmp(app.doctrine_record.text, doctrine_text);
            app.doctrine_record.enabled(row_index) = doctrine_enabled;
            app.doctrine_record.level(row_index) = doctrine_level;

            app.DoctrineChanged();
        end

        % Button pushed function: Button_ResetDoctrineOfRangers
        function Button_ResetDoctrineOfRangersPushed(app, event)
            app.UITable_Doctrine_Rangers_Pistol.Data(:,2)  = num2cell(false(height(app.UITable_Doctrine_Rangers_Pistol.Data),1));
            app.UITable_Doctrine_Rangers_Rifle.Data(:,2)   = num2cell(false(height(app.UITable_Doctrine_Rangers_Rifle.Data),1));
            app.UITable_Doctrine_Rangers_Support.Data(:,2) = num2cell(false(height(app.UITable_Doctrine_Rangers_Support.Data),1));
            app.UITable_Doctrine_Rangers_Elite.Data(:,2)   = num2cell(false(height(app.UITable_Doctrine_Rangers_Elite.Data),1));
            app.doctrine_record.enabled(strcmp(app.doctrine_record.class, app.soldier_class_ranger(1))) = false;
            app.DoctrineChanged();
        end

        % Button pushed function: Button_AllDoctrineOfRangers
        function Button_AllDoctrineOfRangersPushed(app, event)
            app.UITable_Doctrine_Rangers_Pistol.Data(:,2)  = num2cell(true(height(app.UITable_Doctrine_Rangers_Pistol.Data),1));
            app.UITable_Doctrine_Rangers_Rifle.Data(:,2)   = num2cell(true(height(app.UITable_Doctrine_Rangers_Rifle.Data),1));
            app.UITable_Doctrine_Rangers_Support.Data(:,2) = num2cell(true(height(app.UITable_Doctrine_Rangers_Support.Data),1));
            app.UITable_Doctrine_Rangers_Elite.Data(:,2)   = num2cell(true(height(app.UITable_Doctrine_Rangers_Elite.Data),1));
            app.doctrine_record.enabled(strcmp(app.doctrine_record.class, app.soldier_class_ranger(1))) = true;
            app.DoctrineChanged();
        end

        % Button pushed function: Button_ResetDoctrineOfSWAT
        function Button_ResetDoctrineOfSWATPushed(app, event)
            app.UITable_Doctrine_SWAT_Pistol.Data(:,2)  = num2cell(false(height(app.UITable_Doctrine_SWAT_Pistol.Data),1));
            app.UITable_Doctrine_SWAT_Rifle.Data(:,2)   = num2cell(false(height(app.UITable_Doctrine_SWAT_Rifle.Data),1));
            app.UITable_Doctrine_SWAT_Support.Data(:,2) = num2cell(false(height(app.UITable_Doctrine_SWAT_Support.Data),1));
            app.UITable_Doctrine_SWAT_Elite.Data(:,2)   = num2cell(false(height(app.UITable_Doctrine_SWAT_Elite.Data),1));
            app.doctrine_record.enabled(strcmp(app.doctrine_record.class, app.soldier_class_swat(1))) = false;
            app.DoctrineChanged();  
        end

        % Button pushed function: Button_AllDoctrineOfSWAT
        function Button_AllDoctrineOfSWATPushed(app, event)
            app.UITable_Doctrine_SWAT_Pistol.Data(:,2)  = num2cell(true(height(app.UITable_Doctrine_SWAT_Pistol.Data),1));
            app.UITable_Doctrine_SWAT_Rifle.Data(:,2)   = num2cell(true(height(app.UITable_Doctrine_SWAT_Rifle.Data),1));
            app.UITable_Doctrine_SWAT_Support.Data(:,2) = num2cell(true(height(app.UITable_Doctrine_SWAT_Support.Data),1));
            app.UITable_Doctrine_SWAT_Elite.Data(:,2)   = num2cell(true(height(app.UITable_Doctrine_SWAT_Elite.Data),1));
            app.doctrine_record.enabled(strcmp(app.doctrine_record.class, app.soldier_class_swat(1))) = true;
            app.DoctrineChanged();
        end

        % Button pushed function: Button_ResetDoctrineOfCIA
        function Button_ResetDoctrineOfCIAPushed(app, event)
            app.UITable_Doctrine_CIA_Pistol.Data(:,2)  = num2cell(false(height(app.UITable_Doctrine_CIA_Pistol.Data),1));
            app.UITable_Doctrine_CIA_Rifle.Data(:,2)   = num2cell(false(height(app.UITable_Doctrine_CIA_Rifle.Data),1));
            app.UITable_Doctrine_CIA_Support.Data(:,2) = num2cell(false(height(app.UITable_Doctrine_CIA_Support.Data),1));
            app.doctrine_record.enabled(strcmp(app.doctrine_record.class, app.soldier_class_cia(1))) = false;
            app.DoctrineChanged();  
        end

        % Button pushed function: Button_AllDoctrineOfCIA
        function Button_AllDoctrineOfCIAPushed(app, event)
            app.UITable_Doctrine_CIA_Pistol.Data(:,2)  = num2cell(true(height(app.UITable_Doctrine_CIA_Pistol.Data),1));
            app.UITable_Doctrine_CIA_Rifle.Data(:,2)   = num2cell(true(height(app.UITable_Doctrine_CIA_Rifle.Data),1));
            app.UITable_Doctrine_CIA_Support.Data(:,2) = num2cell(true(height(app.UITable_Doctrine_CIA_Support.Data),1));
            app.doctrine_record.enabled(strcmp(app.doctrine_record.class, app.soldier_class_cia(1))) = true;
            app.DoctrineChanged();
        end

        % Value changed function: Slider_Marksmanship, Spinner_Marksmanship
        function Spinner_MarksmanshipValueChanged(app, event)
            value = app.Spinner_Marksmanship.Value;
            app.innate_marksmanship_value = value;
            app.InnateChanged();
        end

        % Value changed function: Slider_AssaultShooting, 
        % ...and 1 other component
        function Spinner_AssaultShootingValueChanged(app, event)
            value = app.Spinner_AssaultShooting.Value;
            app.innate_assaultshooting_value = value;
            app.InnateChanged();
        end

        % Value changed function: DropDown_BattleHonor1
        function DropDown_BattleHonor1ValueChanged(app, event)
            value = app.DropDown_BattleHonor1.Value;
            app.battle_honor_1 = value;
            app.BattleHonorChanged();
        end

        % Value changed function: DropDown_BattleHonor2
        function DropDown_BattleHonor2ValueChanged(app, event)
            value = app.DropDown_BattleHonor2.Value;
            app.battle_honor_2 = value;
            app.BattleHonorChanged();
        end

        % Value changed function: CheckBox_EnableTab_DoctrineOfRanger
        function CheckBox_EnableTab_DoctrineOfRangerValueChanged(app, event)
            value = app.CheckBox_EnableTab_DoctrineOfRanger.Value;
            app.doctrine_enabled_rangers = value;
            app.DoctrineChanged();
        end

        % Value changed function: CheckBox_EnableTab_DoctrineOfSWAT
        function CheckBox_EnableTab_DoctrineOfSWATValueChanged(app, event)
            value = app.CheckBox_EnableTab_DoctrineOfSWAT.Value;
            app.doctrine_enabled_swat    = value;
            app.DoctrineChanged();
        end

        % Value changed function: CheckBox_EnableTab_DoctrineOfCIA
        function CheckBox_EnableTab_DoctrineOfCIAValueChanged(app, event)
            value = app.CheckBox_EnableTab_DoctrineOfCIA.Value;
            app.doctrine_enabled_cia     = value;
            app.DoctrineChanged();
        end

        % Value changing function: Slider_CurveMaxDistance
        function Slider_CurveMaxDistanceValueChanging(app, event)
            changingValue = event.Value;
            app.Spinner_CurveMaxDistance.Value = changingValue;
        end

        % Value changed function: Slider_CurveMaxDistance
        function Slider_CurveMaxDistanceValueChanged(app, event)
            value = app.Slider_CurveMaxDistance.Value;
            app.curve_max_distance = value;
            app.CurveDistanceUpdated();
        end

        % Value changed function: Spinner_CurveMaxDistance
        function Spinner_CurveMaxDistanceValueChanged(app, event)
            value = app.Spinner_CurveMaxDistance.Value;
            app.curve_max_distance = value;
            app.CurveDistanceUpdated();
        end

        % Button pushed function: mButton_7m
        function mButton_7mPushed(app, event)
            app.curve_max_distance = 7;
            app.CurveDistanceUpdated();
        end

        % Button pushed function: mButton_15m
        function mButton_15mPushed(app, event)
            app.curve_max_distance = 15;
            app.CurveDistanceUpdated();     
        end

        % Button pushed function: mButton_25m
        function mButton_25mPushed(app, event)
            app.curve_max_distance = 25;
            app.CurveDistanceUpdated();
        end

        % Button pushed function: mButton_50m
        function mButton_50mPushed(app, event)
            app.curve_max_distance = 50;
            app.CurveDistanceUpdated();
        end

        % Button pushed function: Button_SetRef
        function Button_SetRefPushed(app, event)
            app.SetReference();
        end

        % Button pushed function: Button_ClearRef
        function Button_ClearRefPushed(app, event)
            app.ClearRefrence();
        end

        % Value changed function: CheckBox_CritKill
        function CheckBox_CritKillValueChanged(app, event)
            app.UpdateTTK();
            app.UpdateFinalStatistics();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DoorKickers2Armory';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.selection = uipanel(app.GridLayout);
            app.selection.Title = '枪械选择';
            app.GridLayout2 = uigridlayout(app.selection);
            app.Panel_Filter = uipanel(app.GridLayout2);
            app.Panel_Filter.Title = '过滤';
            app.GridLayout7 = uigridlayout(app.Panel_Filter);
            app.Tree_Filter = uitree(app.GridLayout7, 'checkbox');
            app.Filter_Rangers = uitreenode(app.Tree_Filter);
            app.Filter_Rangers.NodeData = 'Rangers';
            app.Filter_Rangers.Text = 'Rangers';
            app.Filter_Assault = uitreenode(app.Filter_Rangers);
            app.Filter_Assault.NodeData = 'Assault';
            app.Filter_Assault.Text = '突击兵';
            app.Filter_Support = uitreenode(app.Filter_Rangers);
            app.Filter_Support.NodeData = 'Support';
            app.Filter_Support.Text = '支援兵';
            app.Filter_Marksman = uitreenode(app.Filter_Rangers);
            app.Filter_Marksman.NodeData = 'Marksman';
            app.Filter_Marksman.Text = '神射手';
            app.Filter_Grenadier = uitreenode(app.Filter_Rangers);
            app.Filter_Grenadier.NodeData = 'Grenadier';
            app.Filter_Grenadier.Text = '掷弹兵';
            app.Filter_SWAT = uitreenode(app.Tree_Filter);
            app.Filter_SWAT.NodeData = 'SWAT';
            app.Filter_SWAT.Text = 'SWAT';
            app.Filter_SwatLeader = uitreenode(app.Filter_SWAT);
            app.Filter_SwatLeader.NodeData = 'SwatLeader';
            app.Filter_SwatLeader.Text = '领队';
            app.Filter_SwatAssaulter = uitreenode(app.Filter_SWAT);
            app.Filter_SwatAssaulter.NodeData = 'SwatAssaulter';
            app.Filter_SwatAssaulter.Text = '突击兵';
            app.Filter_SwatSapper = uitreenode(app.Filter_SWAT);
            app.Filter_SwatSapper.NodeData = 'SwatSapper';
            app.Filter_SwatSapper.Text = '工程兵';
            app.Filter_SwatMilitia = uitreenode(app.Filter_SWAT);
            app.Filter_SwatMilitia.NodeData = 'SwatMilitia';
            app.Filter_SwatMilitia.Text = '民兵';
            app.Filter_CIA = uitreenode(app.Tree_Filter);
            app.Filter_CIA.NodeData = 'CIA';
            app.Filter_CIA.Text = 'CIA';
            app.Filter_Undercover = uitreenode(app.Filter_CIA);
            app.Filter_Undercover.NodeData = 'Undercover';
            app.Filter_Undercover.Text = '卧底';
            app.Filter_BlackOps = uitreenode(app.Filter_CIA);
            app.Filter_BlackOps.NodeData = 'BlackOps';
            app.Filter_BlackOps.Text = '黑色行动';
            % Assign Checked Nodes
            app.Tree_Filter.CheckedNodesChangedFcn = createCallbackFcn(app, @FilterGuns, true);
            app.GridLayout5 = uigridlayout(app.GridLayout7);
            app.Filter_PrimaryWeapon = uicheckbox(app.GridLayout5);
            app.Filter_PrimaryWeapon.ValueChangedFcn = createCallbackFcn(app, @FilterGuns, true);
            app.Filter_PrimaryWeapon.Text = '主武器';
            app.Filter_PrimaryWeapon.Value = true;
            app.Filter_SecondaryWeapon = uicheckbox(app.GridLayout5);
            app.Filter_SecondaryWeapon.ValueChangedFcn = createCallbackFcn(app, @FilterGuns, true);
            app.Filter_SecondaryWeapon.Text = '副武器';
            app.Filter_NoMuzzleWeapon = uicheckbox(app.GridLayout5);
            app.Filter_NoMuzzleWeapon.ValueChangedFcn = createCallbackFcn(app, @FilterGuns, true);
            app.Filter_NoMuzzleWeapon.Text = '无消音';
            app.Filter_NoMuzzleWeapon.Value = true;
            app.Filter_MuzzleWeapon = uicheckbox(app.GridLayout5);
            app.Filter_MuzzleWeapon.ValueChangedFcn = createCallbackFcn(app, @FilterGuns, true);
            app.Filter_MuzzleWeapon.Text = '带消音';
            app.Gun = uipanel(app.GridLayout2);
            app.Gun.Title = '枪械';
            app.GridLayout8 = uigridlayout(app.Gun);
            app.ListBox_Gun = uilistbox(app.GridLayout8);
            app.ListBox_Gun.Items = {};
            app.ListBox_Gun.ValueChangedFcn = createCallbackFcn(app, @ListBox_GunValueChanged, true);
            app.ListBox_Gun.Value = {};
            app.GridLayout30 = uigridlayout(app.GridLayout8);
            app.Label_3 = uilabel(app.GridLayout30);
            app.Label_3.Text = '可解锁的射击模式';
            app.Lamp_Unlockable = uilamp(app.GridLayout30);
            app.CheckBox_Mounted = uicheckbox(app.GridLayout30);
            app.CheckBox_Mounted.ValueChangedFcn = createCallbackFcn(app, @CheckBox_MountedValueChanged, true);
            app.CheckBox_Mounted.Text = '架枪模式';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Scope = uipanel(app.GridLayout3);
            app.Scope.Title = '瞄准镜';
            app.GridLayout9 = uigridlayout(app.Scope);
            app.ListBox_Scope = uilistbox(app.GridLayout9);
            app.ListBox_Scope.Items = {};
            app.ListBox_Scope.ValueChangedFcn = createCallbackFcn(app, @ListBox_ScopeValueChanged, true);
            app.ListBox_Scope.Value = {};
            app.Panel_Ammo = uipanel(app.GridLayout3);
            app.Panel_Ammo.Title = '弹药';
            app.GridLayout10 = uigridlayout(app.Panel_Ammo);
            app.ListBox_Ammo = uilistbox(app.GridLayout10);
            app.ListBox_Ammo.Items = {};
            app.ListBox_Ammo.ValueChangedFcn = createCallbackFcn(app, @ListBox_AmmoValueChanged, true);
            app.ListBox_Ammo.Value = {};
            app.statistic = uipanel(app.GridLayout);
            app.statistic.Title = '枪械性能';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = '性能曲线';
            app.GridLayout18 = uigridlayout(app.Panel);
            app.Curve = uiaxes(app.GridLayout18);
            xlabel(app.Curve, '距离（m）')
            zlabel(app.Curve, 'Z')
            app.Curve.XLim = [0 50];
            app.Curve.YLim = [0 100];
            app.Curve.XTick = [0 7 15 25 50 100];
            app.Curve.XTickLabel = {'0'; '7'; '15'; '25'; '50'; '100'};
            app.config = uipanel(app.GridLayout);
            app.config.Title = '性能修正';
            app.GridLayout11 = uigridlayout(app.config);
            app.Panel_3 = uipanel(app.GridLayout11);
            app.Panel_3.Title = '人物属性';
            app.GridLayout14 = uigridlayout(app.Panel_3);
            app.Panel_4 = uipanel(app.GridLayout14);
            app.Panel_4.Title = '神射枪法';
            app.GridLayout19 = uigridlayout(app.Panel_4);
            app.Slider_Marksmanship = uislider(app.GridLayout19);
            app.Slider_Marksmanship.ValueChangedFcn = createCallbackFcn(app, @Spinner_MarksmanshipValueChanged, true);
            app.Slider_Marksmanship.ValueChangingFcn = createCallbackFcn(app, @Slider_MarksmanshipValueChanging, true);
            app.Slider_Marksmanship.Value = 50;
            app.Spinner_Marksmanship = uispinner(app.GridLayout19);
            app.Spinner_Marksmanship.ValueChangingFcn = createCallbackFcn(app, @Spinner_MarksmanshipValueChanging, true);
            app.Spinner_Marksmanship.Limits = [0 100];
            app.Spinner_Marksmanship.RoundFractionalValues = 'on';
            app.Spinner_Marksmanship.ValueChangedFcn = createCallbackFcn(app, @Spinner_MarksmanshipValueChanged, true);
            app.Spinner_Marksmanship.Value = 50;
            app.Panel_5 = uipanel(app.GridLayout14);
            app.Panel_5.Title = '突击射法';
            app.GridLayout20 = uigridlayout(app.Panel_5);
            app.Slider_AssaultShooting = uislider(app.GridLayout20);
            app.Slider_AssaultShooting.ValueChangedFcn = createCallbackFcn(app, @Spinner_AssaultShootingValueChanged, true);
            app.Slider_AssaultShooting.ValueChangingFcn = createCallbackFcn(app, @Slider_AssaultShootingValueChanging, true);
            app.Slider_AssaultShooting.Value = 50;
            app.Spinner_AssaultShooting = uispinner(app.GridLayout20);
            app.Spinner_AssaultShooting.ValueChangingFcn = createCallbackFcn(app, @Spinner_AssaultShootingValueChanging, true);
            app.Spinner_AssaultShooting.Limits = [0 100];
            app.Spinner_AssaultShooting.RoundFractionalValues = 'on';
            app.Spinner_AssaultShooting.ValueChangedFcn = createCallbackFcn(app, @Spinner_AssaultShootingValueChanged, true);
            app.Spinner_AssaultShooting.Value = 50;
            app.Panel_6 = uipanel(app.GridLayout14);
            app.Panel_6.Enable = 'off';
            app.Panel_6.Title = '战场技法';
            app.GridLayout20_2 = uigridlayout(app.Panel_6);
            app.Slider_FieldSkills = uislider(app.GridLayout20_2);
            app.Slider_FieldSkills.ValueChangingFcn = createCallbackFcn(app, @Slider_FieldSkillsValueChanging, true);
            app.Slider_FieldSkills.Enable = 'off';
            app.Slider_FieldSkills.Value = 50;
            app.Spinner_FieldSkills = uispinner(app.GridLayout20_2);
            app.Spinner_FieldSkills.ValueChangingFcn = createCallbackFcn(app, @Spinner_FieldSkillsValueChanging, true);
            app.Spinner_FieldSkills.Limits = [0 100];
            app.Spinner_FieldSkills.RoundFractionalValues = 'on';
            app.Spinner_FieldSkills.Enable = 'off';
            app.Spinner_FieldSkills.Value = 50;
            app.Panel_7 = uipanel(app.GridLayout14);
            app.Panel_7.Title = '战斗荣誉';
            app.GridLayout15 = uigridlayout(app.Panel_7);
            app.DropDown_BattleHonor1 = uidropdown(app.GridLayout15);
            app.DropDown_BattleHonor1.Items = {'无', '进攻升级', '防御升级'};
            app.DropDown_BattleHonor1.ValueChangedFcn = createCallbackFcn(app, @DropDown_BattleHonor1ValueChanged, true);
            app.DropDown_BattleHonor1.Value = '无';
            app.DropDown_BattleHonor2 = uidropdown(app.GridLayout15);
            app.DropDown_BattleHonor2.Items = {'无', '进攻升级', '防御升级'};
            app.DropDown_BattleHonor2.ValueChangedFcn = createCallbackFcn(app, @DropDown_BattleHonor2ValueChanged, true);
            app.DropDown_BattleHonor2.Value = '无';
            app.Panel_2 = uipanel(app.GridLayout11);
            app.Panel_2.Title = '作战条令';
            app.GridLayout12 = uigridlayout(app.Panel_2);
            app.TabGroup_DoctrineTroopClass = uitabgroup(app.GridLayout12);
            app.Tab_DoctrineOfRanger = uitab(app.TabGroup_DoctrineTroopClass);
            app.Tab_DoctrineOfRanger.Title = 'Rangers';
            app.GridLayout16 = uigridlayout(app.Tab_DoctrineOfRanger);
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.CheckBox_EnableTab_DoctrineOfRanger = uicheckbox(app.GridLayout17);
            app.CheckBox_EnableTab_DoctrineOfRanger.ValueChangedFcn = createCallbackFcn(app, @CheckBox_EnableTab_DoctrineOfRangerValueChanged, true);
            app.CheckBox_EnableTab_DoctrineOfRanger.Text = '条令生效';
            app.Button_ResetDoctrineOfRangers = uibutton(app.GridLayout17, 'push');
            app.Button_ResetDoctrineOfRangers.ButtonPushedFcn = createCallbackFcn(app, @Button_ResetDoctrineOfRangersPushed, true);
            app.Button_ResetDoctrineOfRangers.Text = '重置';
            app.Button_AllDoctrineOfRangers = uibutton(app.GridLayout17, 'push');
            app.Button_AllDoctrineOfRangers.ButtonPushedFcn = createCallbackFcn(app, @Button_AllDoctrineOfRangersPushed, true);
            app.Button_AllDoctrineOfRangers.Text = '全选';
            app.TabGroup_DoctrineOfRanger = uitabgroup(app.GridLayout16);
            app.Tab_Doctrine_Ranger_Pistol = uitab(app.TabGroup_DoctrineOfRanger);
            app.Tab_Doctrine_Ranger_Pistol.Title = '手枪';
            app.GridLayout28 = uigridlayout(app.Tab_Doctrine_Ranger_Pistol);
            app.UITable_Doctrine_Rangers_Pistol = uitable(app.GridLayout28);
            app.UITable_Doctrine_Rangers_Pistol.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_Rangers_Pistol.RowName = {};
            app.UITable_Doctrine_Rangers_Pistol.ColumnEditable = [false true true false];
            app.UITable_Doctrine_Rangers_Pistol.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_3 = uitab(app.TabGroup_DoctrineOfRanger);
            app.Tab_3.Title = '步枪';
            app.GridLayout29 = uigridlayout(app.Tab_3);
            app.UITable_Doctrine_Rangers_Rifle = uitable(app.GridLayout29);
            app.UITable_Doctrine_Rangers_Rifle.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_Rangers_Rifle.RowName = {};
            app.UITable_Doctrine_Rangers_Rifle.ColumnEditable = [false true true false];
            app.UITable_Doctrine_Rangers_Rifle.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_4 = uitab(app.TabGroup_DoctrineOfRanger);
            app.Tab_4.Title = '支援';
            app.GridLayout29_2 = uigridlayout(app.Tab_4);
            app.UITable_Doctrine_Rangers_Support = uitable(app.GridLayout29_2);
            app.UITable_Doctrine_Rangers_Support.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_Rangers_Support.RowName = {};
            app.UITable_Doctrine_Rangers_Support.ColumnEditable = [false true true false];
            app.UITable_Doctrine_Rangers_Support.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_5 = uitab(app.TabGroup_DoctrineOfRanger);
            app.Tab_5.Title = '精英小队';
            app.GridLayout29_3 = uigridlayout(app.Tab_5);
            app.UITable_Doctrine_Rangers_Elite = uitable(app.GridLayout29_3);
            app.UITable_Doctrine_Rangers_Elite.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_Rangers_Elite.RowName = {};
            app.UITable_Doctrine_Rangers_Elite.ColumnEditable = [false true true false];
            app.UITable_Doctrine_Rangers_Elite.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_DoctrineOfSWAT = uitab(app.TabGroup_DoctrineTroopClass);
            app.Tab_DoctrineOfSWAT.Title = 'SWAT';
            app.GridLayout13_3 = uigridlayout(app.Tab_DoctrineOfSWAT);
            app.GridLayout17_2 = uigridlayout(app.GridLayout13_3);
            app.CheckBox_EnableTab_DoctrineOfSWAT = uicheckbox(app.GridLayout17_2);
            app.CheckBox_EnableTab_DoctrineOfSWAT.ValueChangedFcn = createCallbackFcn(app, @CheckBox_EnableTab_DoctrineOfSWATValueChanged, true);
            app.CheckBox_EnableTab_DoctrineOfSWAT.Text = '条令生效';
            app.Button_ResetDoctrineOfSWAT = uibutton(app.GridLayout17_2, 'push');
            app.Button_ResetDoctrineOfSWAT.ButtonPushedFcn = createCallbackFcn(app, @Button_ResetDoctrineOfSWATPushed, true);
            app.Button_ResetDoctrineOfSWAT.Text = '重置';
            app.Button_AllDoctrineOfSWAT = uibutton(app.GridLayout17_2, 'push');
            app.Button_AllDoctrineOfSWAT.ButtonPushedFcn = createCallbackFcn(app, @Button_AllDoctrineOfSWATPushed, true);
            app.Button_AllDoctrineOfSWAT.Text = '全选';
            app.TabGroup_DoctrineOfSWAT = uitabgroup(app.GridLayout13_3);
            app.Tab_Doctrine_SWAT_Pistol = uitab(app.TabGroup_DoctrineOfSWAT);
            app.Tab_Doctrine_SWAT_Pistol.Title = '手枪';
            app.GridLayout29_4 = uigridlayout(app.Tab_Doctrine_SWAT_Pistol);
            app.UITable_Doctrine_SWAT_Pistol = uitable(app.GridLayout29_4);
            app.UITable_Doctrine_SWAT_Pistol.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_SWAT_Pistol.RowName = {};
            app.UITable_Doctrine_SWAT_Pistol.ColumnEditable = [false true true false];
            app.UITable_Doctrine_SWAT_Pistol.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_7 = uitab(app.TabGroup_DoctrineOfSWAT);
            app.Tab_7.Title = '步枪';
            app.GridLayout29_5 = uigridlayout(app.Tab_7);
            app.UITable_Doctrine_SWAT_Rifle = uitable(app.GridLayout29_5);
            app.UITable_Doctrine_SWAT_Rifle.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_SWAT_Rifle.RowName = {};
            app.UITable_Doctrine_SWAT_Rifle.ColumnEditable = [false true true false];
            app.UITable_Doctrine_SWAT_Rifle.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_8 = uitab(app.TabGroup_DoctrineOfSWAT);
            app.Tab_8.Title = '盾牌';
            app.GridLayout29_6 = uigridlayout(app.Tab_8);
            app.UITable_Doctrine_SWAT_Support = uitable(app.GridLayout29_6);
            app.UITable_Doctrine_SWAT_Support.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_SWAT_Support.RowName = {};
            app.UITable_Doctrine_SWAT_Support.ColumnEditable = [false true true false];
            app.UITable_Doctrine_SWAT_Support.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_9 = uitab(app.TabGroup_DoctrineOfSWAT);
            app.Tab_9.Title = '老兵小队';
            app.GridLayout29_7 = uigridlayout(app.Tab_9);
            app.UITable_Doctrine_SWAT_Elite = uitable(app.GridLayout29_7);
            app.UITable_Doctrine_SWAT_Elite.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_SWAT_Elite.RowName = {};
            app.UITable_Doctrine_SWAT_Elite.ColumnEditable = [false true true false];
            app.UITable_Doctrine_SWAT_Elite.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_DoctrineOfCIA = uitab(app.TabGroup_DoctrineTroopClass);
            app.Tab_DoctrineOfCIA.Title = 'CIA';
            app.GridLayout13_2 = uigridlayout(app.Tab_DoctrineOfCIA);
            app.GridLayout17_3 = uigridlayout(app.GridLayout13_2);
            app.CheckBox_EnableTab_DoctrineOfCIA = uicheckbox(app.GridLayout17_3);
            app.CheckBox_EnableTab_DoctrineOfCIA.ValueChangedFcn = createCallbackFcn(app, @CheckBox_EnableTab_DoctrineOfCIAValueChanged, true);
            app.CheckBox_EnableTab_DoctrineOfCIA.Text = '条令生效';
            app.Button_ResetDoctrineOfCIA = uibutton(app.GridLayout17_3, 'push');
            app.Button_ResetDoctrineOfCIA.ButtonPushedFcn = createCallbackFcn(app, @Button_ResetDoctrineOfCIAPushed, true);
            app.Button_ResetDoctrineOfCIA.Text = '重置';
            app.Button_AllDoctrineOfCIA = uibutton(app.GridLayout17_3, 'push');
            app.Button_AllDoctrineOfCIA.ButtonPushedFcn = createCallbackFcn(app, @Button_AllDoctrineOfCIAPushed, true);
            app.Button_AllDoctrineOfCIA.Text = '全选';
            app.TabGroup_DoctrineOfCIA = uitabgroup(app.GridLayout13_2);
            app.Tab_Doctrine_CIA_Pistol = uitab(app.TabGroup_DoctrineOfCIA);
            app.Tab_Doctrine_CIA_Pistol.Title = '手枪';
            app.GridLayout29_8 = uigridlayout(app.Tab_Doctrine_CIA_Pistol);
            app.UITable_Doctrine_CIA_Pistol = uitable(app.GridLayout29_8);
            app.UITable_Doctrine_CIA_Pistol.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_CIA_Pistol.RowName = {};
            app.UITable_Doctrine_CIA_Pistol.ColumnEditable = [false true true false];
            app.UITable_Doctrine_CIA_Pistol.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_11 = uitab(app.TabGroup_DoctrineOfCIA);
            app.Tab_11.Title = '步枪';
            app.GridLayout29_9 = uigridlayout(app.Tab_11);
            app.UITable_Doctrine_CIA_Rifle = uitable(app.GridLayout29_9);
            app.UITable_Doctrine_CIA_Rifle.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_CIA_Rifle.RowName = {};
            app.UITable_Doctrine_CIA_Rifle.ColumnEditable = [false true true false];
            app.UITable_Doctrine_CIA_Rifle.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.Tab_12 = uitab(app.TabGroup_DoctrineOfCIA);
            app.Tab_12.Title = '潜伏技能';
            app.GridLayout29_10 = uigridlayout(app.Tab_12);
            app.UITable_Doctrine_CIA_Support = uitable(app.GridLayout29_10);
            app.UITable_Doctrine_CIA_Support.ColumnName = {'名称'; '启用'; '当前等级'; '最大等级'};
            app.UITable_Doctrine_CIA_Support.RowName = {};
            app.UITable_Doctrine_CIA_Support.ColumnEditable = [false true true false];
            app.UITable_Doctrine_CIA_Support.CellEditCallback = createCallbackFcn(app, @UITable_Doctrine_CellEdit, true);
            app.compare = uipanel(app.GridLayout);
            app.compare.Title = '性能数据';
            app.GridLayout25 = uigridlayout(app.compare);
            app.UITable_ConstantStatistics = uitable(app.GridLayout25);
            app.UITable_ConstantStatistics.ColumnName = {'性能项'; '当前值'; '参考值'};
            app.UITable_ConstantStatistics.RowName = {};
            app.GridLayout26 = uigridlayout(app.GridLayout25);
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.Button_ClearRef = uibutton(app.GridLayout27, 'push');
            app.Button_ClearRef.ButtonPushedFcn = createCallbackFcn(app, @Button_ClearRefPushed, true);
            app.Button_ClearRef.Text = '清除参考';
            app.Button_SetRef = uibutton(app.GridLayout27, 'push');
            app.Button_SetRef.ButtonPushedFcn = createCallbackFcn(app, @Button_SetRefPushed, true);
            app.Button_SetRef.Text = '设为参考';
            app.Label = uilabel(app.GridLayout27);
            app.Label.Text = '曲线类型:';
            app.DropDown_CurveType = uidropdown(app.GridLayout27);
            app.DropDown_CurveType.Items = {'击杀耗时', '命中率(首发)', '命中率(连发)', '暴击率', '瞄准时间', '恢复时间', '伤害', '穿透力', '射速'};
            app.DropDown_CurveType.ValueChangedFcn = createCallbackFcn(app, @DropDown_CurveTypeValueChanged, true);
            app.DropDown_CurveType.Value = '击杀耗时';
            app.CheckBox_CritKill = uicheckbox(app.GridLayout27);
            app.CheckBox_CritKill.ValueChangedFcn = createCallbackFcn(app, @CheckBox_CritKillValueChanged, true);
            app.CheckBox_CritKill.Text = '暴击即死';
            app.Panel_8 = uipanel(app.GridLayout26);
            app.Panel_8.Title = '曲线最远距离';
            app.GridLayout23 = uigridlayout(app.Panel_8);
            app.Slider_CurveMaxDistance = uislider(app.GridLayout23);
            app.Slider_CurveMaxDistance.Limits = [1 100];
            app.Slider_CurveMaxDistance.ValueChangedFcn = createCallbackFcn(app, @Slider_CurveMaxDistanceValueChanged, true);
            app.Slider_CurveMaxDistance.ValueChangingFcn = createCallbackFcn(app, @Slider_CurveMaxDistanceValueChanging, true);
            app.Slider_CurveMaxDistance.Value = 50;
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.mButton_7m = uibutton(app.GridLayout24, 'push');
            app.mButton_7m.ButtonPushedFcn = createCallbackFcn(app, @mButton_7mPushed, true);
            app.mButton_7m.Text = '7m';
            app.mButton_15m = uibutton(app.GridLayout24, 'push');
            app.mButton_15m.ButtonPushedFcn = createCallbackFcn(app, @mButton_15mPushed, true);
            app.mButton_15m.Text = '15m';
            app.mButton_25m = uibutton(app.GridLayout24, 'push');
            app.mButton_25m.ButtonPushedFcn = createCallbackFcn(app, @mButton_25mPushed, true);
            app.mButton_25m.Text = '25m';
            app.mButton_50m = uibutton(app.GridLayout24, 'push');
            app.mButton_50m.ButtonPushedFcn = createCallbackFcn(app, @mButton_50mPushed, true);
            app.mButton_50m.Text = '50m';
            app.Spinner_CurveMaxDistance = uispinner(app.GridLayout24);
            app.Spinner_CurveMaxDistance.Limits = [1 100];
            app.Spinner_CurveMaxDistance.RoundFractionalValues = 'on';
            app.Spinner_CurveMaxDistance.ValueChangedFcn = createCallbackFcn(app, @Spinner_CurveMaxDistanceValueChanged, true);
            app.Spinner_CurveMaxDistance.Value = 50;
            app.Panel_9 = uipanel(app.GridLayout26);
            app.Panel_9.Title = '当前枪械';
            app.GridLayout21 = uigridlayout(app.Panel_9);
            app.UITable_CurrentWeapon = uitable(app.GridLayout21);
            app.UITable_CurrentWeapon.ColumnName = {'枪械名称'; '瞄准镜'; '弹药'; '架枪模式'};
            app.UITable_CurrentWeapon.RowName = {};
            app.Panel_10 = uipanel(app.GridLayout26);
            app.Panel_10.Title = '参考枪械';
            app.GridLayout22 = uigridlayout(app.Panel_10);
            app.UITable_ReferenceWeapon = uitable(app.GridLayout22);
            app.UITable_ReferenceWeapon.ColumnName = {'枪械名称'; '瞄准镜'; '弹药'; '架枪模式'};
            app.UITable_ReferenceWeapon.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DoorKickersArmory

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end